classdef FinalProjectBME120 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Rotate                 matlab.ui.control.Button
        Exit                   matlab.ui.control.Button
        Brightness             matlab.ui.control.Slider
        BrightnessSliderLabel  matlab.ui.control.Label
        Blue                   matlab.ui.control.Slider
        BlueSliderLabel        matlab.ui.control.Label
        Green                  matlab.ui.control.Slider
        GreenSliderLabel       matlab.ui.control.Label
        Red                    matlab.ui.control.Slider
        RedSliderLabel         matlab.ui.control.Label
        Load                   matlab.ui.control.Button
        Picture                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        PictureData % Properties stores the loaded image, Any modification to the image, such as color or brightness adjustment.
    end
    
    methods (Access = private)
        function ModifyPicture(app)
            if isempty(app.PictureData)
                return
            end
            NewPicture = double(app.PictureData);
            NewPicture(:,:,1) = NewPicture(:,:,1) + ...
                128*app.Red.Value + ...
                128*app.Brightness.Value;
        
            NewPicture(:,:,2) = NewPicture(:,:,2) + ...
                128*app.Green.Value + ...
                128*app.Brightness.Value;
        
            NewPicture(:,:,3) = NewPicture(:,:,3) + ...
                128*app.Blue.Value + ...
                128*app.Brightness.Value;
       
            NewPicture(NewPicture > 255) = 255;
            NewPicture(NewPicture < 0) = 0;
        
            NewPicture = uint8(NewPicture);
        
            imshow(NewPicture,'Parent',app.Picture)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            [filename, path] = uigetfile('*.jpg');
            if filename == 0
                return
            end
            filename = fullfile(path, filename);
            app.PictureData = imread(filename);
            imshow(app.PictureData,'Parent',app.Picture)
        end

        % Value changed function: Red
        function RedValueChanged(app, event)
            ModifyPicture(app);
        end

        % Value changed function: Green
        function GreenValueChanged(app, event)
            ModifyPicture(app);
        end

        % Value changed function: Blue
        function BlueValueChanged(app, event)
            ModifyPicture(app);
        end

        % Value changed function: Brightness
        function BrightnessValueChanged(app, event)
            ModifyPicture(app);
        end

        % Button pushed function: Exit
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: Rotate
        function RotateButtonPushed(app, event)
            if isempty(app.PictureData)
                return
            end
            app.PictureData = rot90(app.PictureData);
            imshow(app.PictureData,'Parent',app.Picture)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Picture = uiaxes(app.UIFigure);
            title(app.Picture, 'Picture')
            xlabel(app.Picture, 'X')
            ylabel(app.Picture, 'Y')
            zlabel(app.Picture, 'Z')
            app.Load = uibutton(app.UIFigure, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'Load';
            app.RedSliderLabel = uilabel(app.UIFigure);
            app.RedSliderLabel.Text = 'Red';
            app.Red = uislider(app.UIFigure);
            app.Red.Limits = [-1 1];
            app.Red.ValueChangedFcn = createCallbackFcn(app, @RedValueChanged, true);
            app.GreenSliderLabel = uilabel(app.UIFigure);
            app.GreenSliderLabel.Text = 'Green';
            app.Green = uislider(app.UIFigure);
            app.Green.Limits = [-1 1];
            app.Green.ValueChangedFcn = createCallbackFcn(app, @GreenValueChanged, true);
            app.BlueSliderLabel = uilabel(app.UIFigure);
            app.BlueSliderLabel.Text = 'Blue';
            app.Blue = uislider(app.UIFigure);
            app.Blue.Limits = [-1 1];
            app.Blue.ValueChangedFcn = createCallbackFcn(app, @BlueValueChanged, true);
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.Brightness = uislider(app.UIFigure);
            app.Brightness.Limits = [-1 1];
            app.Brightness.ValueChangedFcn = createCallbackFcn(app, @BrightnessValueChanged, true);
            app.Exit = uibutton(app.UIFigure, 'push');
            app.Exit.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.Exit.Text = 'Exit';
            app.Rotate = uibutton(app.UIFigure, 'push');
            app.Rotate.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.Rotate.Text = 'Rotate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProjectBME120

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end